classdef rendezvousGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        rendezvousGUI                   matlab.ui.Figure
        file                            matlab.ui.container.Menu
        generateReport                  matlab.ui.container.Menu
        uitoolbar1                      matlab.ui.container.Toolbar
        uitoggletool1                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                   matlab.ui.container.toolbar.ToggleTool
        uipanel13                       matlab.ui.container.Panel
        optBurn1OnlyCheckbox            matlab.ui.control.CheckBox
        uipanel12                       matlab.ui.container.Panel
        text78                          matlab.ui.control.Label
        text77                          matlab.ui.control.Label
        paramWgtSlider                  matlab.ui.control.Slider
        text76                          matlab.ui.control.Label
        text72                          matlab.ui.control.Label
        searchDurationText              matlab.ui.control.EditField
        text71                          matlab.ui.control.Label
        uipanel10                       matlab.ui.container.Panel
        startEpochDateLabel             matlab.ui.control.Label
        text64                          matlab.ui.control.Label
        initialEpochText                matlab.ui.control.EditField
        text63                          matlab.ui.control.Label
        computeRendezvousButton         matlab.ui.control.Button
        text25                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        dvMan1InfoText                  matlab.ui.control.TextArea
        dvMan2InfoText                  matlab.ui.control.TextArea
        xfrOrbitText                    matlab.ui.control.TextArea
        uipanel4                        matlab.ui.container.Panel
        finalSelectCelBodyOrbit         matlab.ui.control.DropDown
        text59                          matlab.ui.control.Label
        text58                          matlab.ui.control.Label
        finOrbitEPOCHText               matlab.ui.control.EditField
        finOrbitMEANText                matlab.ui.control.EditField
        text57                          matlab.ui.control.Label
        text56                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        text18                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        text14                          matlab.ui.control.Label
        text13                          matlab.ui.control.Label
        text12                          matlab.ui.control.Label
        text11                          matlab.ui.control.Label
        finOrbitARGText                 matlab.ui.control.EditField
        finOrbitRAANText                matlab.ui.control.EditField
        finOrbitINCText                 matlab.ui.control.EditField
        finOrbitECCText                 matlab.ui.control.EditField
        finOrbitSMAText                 matlab.ui.control.EditField
        uipanel3                        matlab.ui.container.Panel
        iniSelectCelBodyOrbit           matlab.ui.control.DropDown
        text55                          matlab.ui.control.Label
        text54                          matlab.ui.control.Label
        iniOrbitEPOCHText               matlab.ui.control.EditField
        iniOrbitMEANText                matlab.ui.control.EditField
        text53                          matlab.ui.control.Label
        text52                          matlab.ui.control.Label
        iniArgPeri                      matlab.ui.control.Label
        iniRAAN                         matlab.ui.control.Label
        iniInc                          matlab.ui.control.Label
        iniEcc                          matlab.ui.control.Label
        iniSMA                          matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        text4                           matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
        iniOrbitARGText                 matlab.ui.control.EditField
        iniOrbitRAANText                matlab.ui.control.EditField
        iniOrbitINCText                 matlab.ui.control.EditField
        iniOrbitECCText                 matlab.ui.control.EditField
        iniOrbitSMAText                 matlab.ui.control.EditField
        uipanel2                        matlab.ui.container.Panel
        text1                           matlab.ui.control.Label
        orbitingAboutCombo              matlab.ui.control.DropDown
        orbitDispAxes                   matlab.ui.control.UIAxes
        orbitContMenu                   matlab.ui.container.ContextMenu
        getINIOrbitFromSFSFile          matlab.ui.container.Menu
        getINIOrbitFromKSP              matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu  matlab.ui.container.Menu
        copyOrbitToClipboardMenu1       matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu1    matlab.ui.container.Menu
        orbitContMenuWithGetDateTime    matlab.ui.container.ContextMenu
        enterUTAsDateTime               matlab.ui.container.Menu
        getINIOrbitFromSFSFile_1        matlab.ui.container.Menu
        getINIOrbitFromKSP_1            matlab.ui.container.Menu
        getOrbitFromKSPActiveVesselMenu_1  matlab.ui.container.Menu
        copyOrbitToClipboardMenu1_1     matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu1_1  matlab.ui.container.Menu
        finOrbitContMenu                matlab.ui.container.ContextMenu
        getFinalOrbitFromSFSFile        matlab.ui.container.Menu
        getFinalOrbitFromKSP            matlab.ui.container.Menu
        getFinalOrbitFromKSPActiveVesselMenu  matlab.ui.container.Menu
        copyOrbitToClipboardMenu2       matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu2    matlab.ui.container.Menu
        finOrbitContMenuWithGetDateTime  matlab.ui.container.ContextMenu
        enterFinUTAsDateTime            matlab.ui.container.Menu
        getFinalOrbitFromSFSFile_1      matlab.ui.container.Menu
        getFinalOrbitFromKSP_1          matlab.ui.container.Menu
        getFinalOrbitFromKSPActiveVesselMenu_1  matlab.ui.container.Menu
        Untitled_7                      matlab.ui.container.Menu
        pasteOrbitFromClipboardMenu2_1  matlab.ui.container.Menu
        enterStartEpochUTAsDateTime     matlab.ui.container.ContextMenu
        enterRendStartEpochUTAsDateTime  matlab.ui.container.Menu
        uploadManuever1ToKSP            matlab.ui.container.ContextMenu
        uploadManeuver1                 matlab.ui.container.Menu
        uploadManuever2ToKSP            matlab.ui.container.ContextMenu
        uploadManeuver2                 matlab.ui.container.Menu
    end


    methods (Access = private)
        function edit25_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to meanAnomText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit34_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to edit34 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function epochText_Callback(~, hObject, ~, ~)
            % hObject    handle to epochText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of epochText as text
            %        str2double(get(hObject,'String')) returns contents of epochText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        function epochText_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to epochText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function meanAnomText_Callback(~, hObject, ~, ~)
            % hObject    handle to meanAnomText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of meanAnomText as text
            %        str2double(get(hObject,'String')) returns contents of meanAnomText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        function meanAnomText_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to meanAnomText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu2_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu4_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to popupmenu4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu5_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to popupmenu5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.

            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2];
            interactiveTools(event.Source == interactiveTools) = [];

            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');

            % Set figure interactions to 'off'.
            datacursormode(app.rendezvousGUI, 'off')
            rotate3d(app.rendezvousGUI, 'off');
            pan(app.rendezvousGUI, 'off');
            zoom(app.rendezvousGUI,'off');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function rendezvousGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to rendezvousGUI (see VARARGIN)

            % Choose default command line output for rendezvousGUI
            handles.output = hObject;
            centerUIFigure(app.rendezvousGUI);
            applySelectedThemeToApp(app);
            
            % Update handles structure
            guidata(hObject, handles);

            %Possible fix for people with display issues.
%             checkForCharUnitsInGUI(hObject);

            mainGUIHandle = varargin{1};
            mainUserData = get(mainGUIHandle, 'UserData');
            userData = cell(10);
            userData{1,1} = mainGUIHandle;
            set(hObject, 'UserData', userData);

            userData = get(mainGUIHandle,'UserData');
            celBodyData = userData{1,1};

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            allNames = ma_getSortedBodyNames(celBodyData);
            allNamesComboString = char(cap1stLetter(allNames));
            set(handles.orbitingAboutCombo,'String',allNamesComboString);
            set(handles.orbitingAboutCombo,'Value',5);
            orbitingAboutCombo_Callback(app, evt);

            initialEpochText_Callback(app, evt);
%             searchDurationText_Callback(handles.searchDurationText, [], handles);

            hold on;
            axes(app.orbitDispAxes);
            set(app.orbitDispAxes,'XTickLabel',[]);
            set(app.orbitDispAxes,'YTickLabel',[]);
            set(app.orbitDispAxes,'ZTickLabel',[]);
            grid on;
            view(3);

            celBodyData = mainUserData{1,1};
            contents = cellstr(get(handles.orbitingAboutCombo,'String'));
            bodyName = contents{get(handles.orbitingAboutCombo,'Value')};
            bodyInfo = celBodyData.(strtrim(lower(bodyName)));
%             dRad = bodyInfo.radius;
%             [X,Y,Z] = sphere(30);
%             surf(dRad*X,dRad*Y,dRad*Z);
%             colormap(bodyInfo.bodycolor);
            ma_initOrbPlot(app.rendezvousGUI, app.orbitDispAxes, bodyInfo);
            axis equal;
            hold off;

            paramWgtSlider_Callback(app, evt);
        end

        % Button pushed function: computeRendezvousButton
        function computeRendezvousButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to computeRendezvousButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.rendezvousGUI,'UserData');
            mainUserData = get(userData{1,1}, 'UserData');
            celBodyData = mainUserData{1,1};

            contents = cellstr(get(handles.orbitingAboutCombo,'String'));
            cbName = strtrim(lower(contents{get(handles.orbitingAboutCombo,'Value')}));

            bodyInfo = celBodyData.(cbName);
            parentBodyInfo = bodyInfo.getParBodyInfo(celBodyData);
            if(isempty(parentBodyInfo))
                soiRadiusParent = Inf;
            else
                soiRadiusParent = getSOIRadius(bodyInfo, parentBodyInfo);
            end

            gmuXfr = bodyInfo.gm;

            errMsg = {};

            iniOrbit(2) = str2double(get(handles.iniOrbitECCText, 'String'));
            enteredStr = get(handles.iniOrbitECCText,'String');
            numberName = 'Initial orbit eccentricity';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(iniOrbit(2), numberName, lb, ub, isInt, errMsg, enteredStr);
            if(not(isempty(errMsg)))
                msgbox(errMsg,'Errors were found in departure burn calculation inputs','error');
                return;
            end

            finOrbit(2) = str2double(get(handles.finOrbitECCText, 'String'));
            enteredStr = get(handles.finOrbitECCText,'String');
            numberName = 'Final orbit eccentricity';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(finOrbit(2), numberName, lb, ub, isInt, errMsg, enteredStr);
            if(not(isempty(errMsg)))
                msgbox(errMsg,'Errors were found in departure burn calculation inputs','error');
                return;
            end

            iniOrbit(1) = str2double(get(handles.iniOrbitSMAText, 'String'));
            enteredStr = get(handles.iniOrbitSMAText,'String');
            if(iniOrbit(2) < 1.0)
                lb = 1;
                ub = Inf;
                numberName = 'Initial eccentric orbit semi-major axis';
            else
                lb = -Inf;
                ub = -1;
                numberName = 'Initial hyperbolic orbit semi-major axis';
            end
            isInt = false;
            errMsg = validateNumber(iniOrbit(1), numberName, lb, ub, isInt, errMsg, enteredStr);

            iniOrbit(3) = str2double(get(handles.iniOrbitINCText, 'String'));
            enteredStr = get(handles.iniOrbitINCText,'String');
            numberName = 'Initial orbit inclination';
            lb = 0;
            ub = 180;
            isInt = false;
            errMsg = validateNumber(iniOrbit(3), numberName, lb, ub, isInt, errMsg, enteredStr);
            iniOrbit(3)=iniOrbit(3)*pi/180;

            iniOrbit(4) = str2double(get(handles.iniOrbitRAANText, 'String'));
            enteredStr = get(handles.iniOrbitRAANText,'String');
            numberName = 'Initial orbit RAAN';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(iniOrbit(4), numberName, lb, ub, isInt, errMsg, enteredStr);
            iniOrbit(4)=iniOrbit(4)*pi/180;

            iniOrbit(5) = str2double(get(handles.iniOrbitARGText, 'String'));
            enteredStr = get(handles.iniOrbitARGText,'String');
            numberName = 'Initial orbit argument of periapse';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(iniOrbit(5), numberName, lb, ub, isInt, errMsg, enteredStr);
            iniOrbit(5)=iniOrbit(5)*pi/180;

            iniOrbit(6) = str2double(get(handles.iniOrbitMEANText, 'String'));
            enteredStr = get(handles.iniOrbitMEANText,'String');
            numberName = 'Initial orbit mean anomaly at epoch';
            lb = -180;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(iniOrbit(6), numberName, lb, ub, isInt, errMsg, enteredStr);
            iniOrbit(6)=iniOrbit(6)*pi/180;

            iniOrbit(7) = str2double(get(handles.iniOrbitEPOCHText, 'String'));
            enteredStr = get(handles.iniOrbitEPOCHText,'String');
            numberName = 'Initial orbit epoch';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(iniOrbit(7), numberName, lb, ub, isInt, errMsg, enteredStr);

            finOrbit(1) = str2double(get(handles.finOrbitSMAText, 'String'));
            enteredStr = get(handles.finOrbitSMAText,'String');
            if(finOrbit(2) < 1.0)
                lb = 1;
                ub = Inf;
                numberName = 'Final eccentric orbit semi-major axis';
            else
                lb = -Inf;
                ub = -1;
                numberName = 'Final hyperbolic orbit semi-major axis';
            end
            isInt = false;
            errMsg = validateNumber(finOrbit(1), numberName, lb, ub, isInt, errMsg, enteredStr);

            finOrbit(3) = str2double(get(handles.finOrbitINCText, 'String'));
            enteredStr = get(handles.finOrbitINCText,'String');
            numberName = 'Final orbit inclination';
            lb = 0;
            ub = 180;
            isInt = false;
            errMsg = validateNumber(finOrbit(3), numberName, lb, ub, isInt, errMsg, enteredStr);
            finOrbit(3)=finOrbit(3)*pi/180;

            finOrbit(4) = str2double(get(handles.finOrbitRAANText, 'String'));
            enteredStr = get(handles.finOrbitRAANText,'String');
            numberName = 'Final orbit RAAN';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(finOrbit(4), numberName, lb, ub, isInt, errMsg, enteredStr);
            finOrbit(4)=finOrbit(4)*pi/180;

            finOrbit(5) = str2double(get(handles.finOrbitARGText, 'String'));
            enteredStr = get(handles.finOrbitARGText,'String');
            numberName = 'Final orbit argument of periapse';
            lb = 0;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(finOrbit(5), numberName, lb, ub, isInt, errMsg, enteredStr);
            finOrbit(5)=finOrbit(5)*pi/180;

            finOrbit(6) = str2double(get(handles.finOrbitMEANText, 'String'));
            enteredStr = get(handles.finOrbitMEANText,'String');
            numberName = 'Final orbit mean anomaly at epoch';
            lb = -180;
            ub = 360;
            isInt = false;
            errMsg = validateNumber(finOrbit(6), numberName, lb, ub, isInt, errMsg, enteredStr);
            finOrbit(6)=finOrbit(6)*pi/180;

            finOrbit(7) = str2double(get(handles.finOrbitEPOCHText, 'String'));
            enteredStr = get(handles.finOrbitEPOCHText,'String');
            numberName = 'Final orbit epoch';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(finOrbit(7), numberName, lb, ub, isInt, errMsg, enteredStr);

            startEpoch = str2double(get(handles.initialEpochText, 'String'));
            enteredStr = get(handles.initialEpochText,'String');
            numberName = 'Search initial epoch';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(startEpoch, numberName, lb, ub, isInt, errMsg, enteredStr);

            searchDuration = str2double(get(handles.searchDurationText, 'String'));
            enteredStr = get(handles.searchDurationText,'String');
            numberName = 'Search duration';
            lb = 0;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(searchDuration, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                try
                    bodyInfo1 = getBodyInfoMatchingOrbit(iniOrbit, celBodyData);
                    if(~isempty(bodyInfo1))
                        gmuXfr1 = getParentGM(bodyInfo1, celBodyData);
                    else
                        gmuXfr1 = gmuXfr;
                    end
                    iniOrbit(8) = gmuXfr1;

                    if(iniOrbit(1) > 0)
                        p1 = computePeriod(iniOrbit(1), gmuXfr1);
                    else
                        n1 = computeMeanMotion(iniOrbit(1), gmuXfr1);

                        iniHyTruMax = AngleZero2Pi(real(computeTrueAFromRadiusEcc(1.0*soiRadiusParent, iniOrbit(1), iniOrbit(2))));
                        iniHyTruMin = -iniHyTruMax;

                        MA1 = computeMeanFromTrueAnom(iniHyTruMin, iniOrbit(2));
                        MA2 = computeMeanFromTrueAnom(iniHyTruMax, iniOrbit(2));
                        %             MAEpoch = computeMeanFromTrueAnom(iniOrbit(6), iniOrbit(2));
                        MAEpoch = iniOrbit(6);
                        finOrbitEpoch = iniOrbit(7);

                        dMASubtract1 = MAEpoch - MA1;
                        dTime1 = dMASubtract1/n1;
                        epoch1 = finOrbitEpoch - dTime1;

                        MAStartEpoch = (startEpoch - epoch1)*n1 + MA1;

                        %             dMA = MA2-MA1;
                        dMA = MA2-MAStartEpoch;
                        p1 = dMA/n1;
                    end

                    bodyInfo2 = getBodyInfoMatchingOrbit(finOrbit, celBodyData);
                    if(~isempty(bodyInfo2))
                        gmuXfr2 = getParentGM(bodyInfo2, celBodyData);
                    else
                        gmuXfr2 = gmuXfr;
                    end
                    finOrbit(8) = gmuXfr2;

                    if(finOrbit(1) > 0)
                        p2 = computePeriod(finOrbit(1), gmuXfr2);
                    else
                        n2 = computeMeanMotion(finOrbit(1), gmuXfr2);

                        iniHyTruMax = AngleZero2Pi(real(computeTrueAFromRadiusEcc(1.0*soiRadiusParent, finOrbit(1), finOrbit(2))));
                        iniHyTruMin = -iniHyTruMax;

                        MA1 = computeMeanFromTrueAnom(iniHyTruMin, finOrbit(2));
                        MA2 = computeMeanFromTrueAnom(iniHyTruMax, finOrbit(2));
                        %             MAEpoch = computeMeanFromTrueAnom(finOrbit(6), finOrbit(2));
                        MAEpoch = finOrbit(6);
                        finOrbitEpoch = finOrbit(7);

                        dMASubtract1 = MAEpoch - MA1;
                        dTime1 = dMASubtract1/n2;
                        epoch1 = finOrbitEpoch - dTime1;

                        MAStartEpoch = (startEpoch - epoch1)*n2 + MA1;

                        %             dMA = MA2-MA1;
                        dMA = MA2-MAStartEpoch;
                        p2 = dMA/n2;
                    end

                    if(finOrbit(2) >= 1.0 && iniOrbit(2) < 1.0)
                        duration = p2;
                    elseif(finOrbit(2) < 1.0 && iniOrbit(2) >= 1.0)
                        duration = p1;
                    elseif(finOrbit(2) >= 1.0 && iniOrbit(2) >= 1.0)
                        duration = min(p1,p2);
                    else
                        duration = 1.5*max(p1,p2);
                    end
                    
                    endEpoch = startEpoch + searchDuration;

                    weights = app.paramWgtSlider.UserData;
                    onlyOptBurn1 = logical(get(handles.optBurn1OnlyCheckbox,'Value'));

                    objFunc = @(x) rendezvousObjFunc(x, iniOrbit, finOrbit, gmuXfr, weights, onlyOptBurn1);
                    x0 = [startEpoch+searchDuration/2 duration/2];
                    lb = [startEpoch 1000];
                    ub = [endEpoch duration];

                    if(isempty(objFunc(x0)) || isnan(objFunc(x0)) || ~isfinite(objFunc(x0)))
                        error('Cannot compute rendezvous: objective function at initial point is invalid.  Please alter your inputs.');
                    end

                    if(finOrbit(2) >= 1.0 ||  iniOrbit(2) >= 1.0)
                        A = [1 1];
                        b = endEpoch;
                    else
                        A = [];
                        b = [];
                    end

                    minPe = bodyInfo.radius + bodyInfo.atmohgt + 1;
                    maxAp = getSOIRadius(bodyInfo, parentBodyInfo) - 1;
                    nonlcon = @(x)rendezvousNonlconFunc(x, iniOrbit, finOrbit, gmuXfr, minPe, maxAp, onlyOptBurn1);

                    hUiProgDlg = uiprogressdlg(app.rendezvousGUI, 'Title','Rendezvous Maneuver Sequencer');
                    
                    [x,~,~,~,~] = multiStartCommonRun('Computing Rendezvous Maneuvers...', 20, objFunc, x0, A, b, lb, ub, nonlcon, hUiProgDlg);
                    [~, ~, deltaV1, deltaV2, ~, ~, xfrOrbit, deltaV1NTW, deltaV2NTW] = objFunc(x);

                    time1 = x(1);
                    time2 = time1 + x(2);
                    timeArr = [time1 time2];

                    iniOrbBodyInfo = getBodyInfoStructFromOrbit(iniOrbit);
                    [rVect, vVect] = getStateAtTime(iniOrbBodyInfo, time1, gmuXfr1);
                    [~, ~, ~, ~, ~, burnTAINIOrbit] = getKeplerFromState(rVect,vVect,gmuXfr);

                    xfrOrbBodyInfo = getBodyInfoStructFromOrbit(xfrOrbit);
                    [rVect, vVect] = getStateAtTime(xfrOrbBodyInfo, time2, gmuXfr);
                    [~, ~, ~, ~, ~, burnTAXfrOrbit] = getKeplerFromState(rVect,vVect,gmuXfr);

                    form = '%9.3f';
                    paddLen = 32;
                    xferOrbitText{1} = ['Transfer Orbit about ', cap1stLetter(lower(cbName))];
                    printXferOrbitToTextbox(handles.xfrOrbitText, xferOrbitText, xfrOrbit, form, paddLen)

                    paddLen = 29;
                    form = '%9.5f';
                    burn1InfoText{1} = 'Transfer Orbit Injection Burn ';
                    burn1InfoText{end+1} = '(Relative to initial orbit)';
                    dv1Text = printDVManeuverInfoToTextbox(handles.dvMan1InfoText, burn1InfoText, deltaV1NTW, burnTAINIOrbit, iniOrbit, gmuXfr, form, paddLen, time1);

                    paddLen = 26;
                    form = '%9.5f';
                    burn2InfoText{1} = 'Final Orbit Injection Burn ';
                    burn2InfoText{end+1} = '(Relative to transfer orbit)';
                    dv2Text = printDVManeuverInfoToTextbox(handles.dvMan2InfoText, burn2InfoText, deltaV2NTW, burnTAXfrOrbit, xfrOrbit, gmuXfr, form, paddLen, time2);

                    plotRendezvous(handles.orbitDispAxes, timeArr, bodyInfo, iniOrbit, finOrbit, xfrOrbit, deltaV1, deltaV2, celBodyData);

                    dv1ManTextUserData = get(handles.dvMan1InfoText,'UserData');
                    dv1ManTextUserData(1) = 0;
                    dv1ManTextUserData(2) = time1;
                    set(handles.dvMan1InfoText,'UserData',dv1ManTextUserData);

                    dv2ManTextUserData = get(handles.dvMan2InfoText,'UserData');
                    dv2ManTextUserData(1) = 0;
                    dv2ManTextUserData(2) = time2;
                    set(handles.dvMan2InfoText,'UserData',dv2ManTextUserData);

                    results = struct();
                    results.dv1Text = dv1Text;
                    results.dv2Text = dv2Text;
                    results.timeArr = timeArr;
                    results.bodyInfo = bodyInfo;
                    results.iniOrbit = iniOrbit;
                    results.finOrbit = finOrbit;
                    results.xfrOrbit = xfrOrbit;
                    results.deltaV1 = deltaV1;
                    results.deltaV2 = deltaV2;

                    userData{2,1} = results;
                    set(handles.rendezvousGUI,'UserData',userData);
                catch ME
                    msgbox(ME.message);
                end
            else
                msgbox(errMsg,'Errors were found in orbit change calculation inputs','error');
            end
        end

        % Menu selected function: copyOrbitToClipboardMenu1, 
        % ...and 1 other component
        function copyOrbitToClipboardMenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyOrbitToClipboardMenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.rendezvousGUI,'UserData');
%             results = userData{2,1};
            mainUserData = get(userData{1,1}, 'UserData');
            celBodyData = mainUserData{1,1};

            contents = cellstr(get(handles.orbitingAboutCombo,'String'));
            cbName = strtrim(lower(contents{get(handles.orbitingAboutCombo,'Value')}));
            bodyInfo = celBodyData.(cbName);

            copyOrbitToClipboardFromText(handles.iniOrbitEPOCHText, handles.iniOrbitSMAText, handles.iniOrbitECCText, ...
                handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText, ...
                handles.iniOrbitMEANText, false, bodyInfo.id);
        end

        % Menu selected function: copyOrbitToClipboardMenu2
        function copyOrbitToClipboardMenu2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to copyOrbitToClipboardMenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.rendezvousGUI,'UserData');
%             results = userData{2,1};
            mainUserData = get(userData{1,1}, 'UserData');
            celBodyData = mainUserData{1,1};

            contents = cellstr(get(handles.orbitingAboutCombo,'String'));
            cbName = strtrim(lower(contents{get(handles.orbitingAboutCombo,'Value')}));
            bodyInfo = celBodyData.(cbName);

            copyOrbitToClipboardFromText(handles.finOrbitEPOCHText, handles.finOrbitSMAText, handles.finOrbitECCText, ...
                handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText, ...
                handles.finOrbitMEANText, false, bodyInfo.id);
        end

        % Menu selected function: enterRendStartEpochUTAsDateTime
        function enterRendStartEpochUTAsDateTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterRendStartEpochUTAsDateTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'Value')));

            output = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output);
            secUT = output.output{1};

            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                initialEpochText_Callback(app,evt);
            end
        end

        % Menu selected function: enterFinUTAsDateTime, enterUTAsDateTime
        function enterUTAsDateTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'Value')));

            output = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output);
            secUT = output.output{1};

            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));
            end
        end

        % Value changed function: finOrbitARGText
        function finOrbitARGText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to finOrbitARGText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of finOrbitARGText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitARGText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: finOrbitECCText
        function finOrbitECCText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to finOrbitECCText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of finOrbitECCText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitECCText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: finOrbitEPOCHText
        function finOrbitEPOCHText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to finOrbitEPOCHText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of finOrbitEPOCHText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitEPOCHText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: finOrbitINCText
        function finOrbitINCText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to finOrbitINCText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of finOrbitINCText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitINCText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: finOrbitMEANText
        function finOrbitMEANText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to finOrbitMEANText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of finOrbitMEANText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitMEANText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: finOrbitRAANText
        function finOrbitRAANText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to finOrbitRAANText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of finOrbitRAANText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitRAANText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: finOrbitSMAText
        function finOrbitSMAText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to finOrbitSMAText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of finOrbitSMAText as text
            %        str2double(get(hObject,'String')) returns contents of finOrbitSMAText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: finalSelectCelBodyOrbit
        function finalSelectCelBodyOrbit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to finalSelectCelBodyOrbit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns finalSelectCelBodyOrbit contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from finalSelectCelBodyOrbit
            contents = cellstr(get(hObject,'String'));
            selName = contents{get(hObject,'Value')};

            userData = get(handles.rendezvousGUI,'UserData');
            mainUserData = get(userData{1,1},'UserData');
            celBodyData = mainUserData{1,1};

            if(~isempty(selName))
                bodyInfo = celBodyData.(lower(selName));
                set(handles.finOrbitSMAText, 'String', fullAccNum2Str(bodyInfo.sma));
                set(handles.finOrbitECCText, 'String', fullAccNum2Str(bodyInfo.ecc));
                set(handles.finOrbitINCText, 'String', fullAccNum2Str(bodyInfo.inc));
                set(handles.finOrbitRAANText, 'String', fullAccNum2Str(bodyInfo.raan));
                set(handles.finOrbitARGText, 'String', fullAccNum2Str(bodyInfo.arg));
                set(handles.finOrbitMEANText, 'String', fullAccNum2Str(bodyInfo.mean));
                set(handles.finOrbitEPOCHText, 'String', fullAccNum2Str(bodyInfo.epoch));
                set(hObject, 'Value', 1);
            end
        end

        % Menu selected function: generateReport
        function generateReport_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to generateReport (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [~, handlesR] = genericReport(1, 'DOUBLE DELTA-V MANEUVER', 'RENDEZVOUS MANEUVER SEQUENCER');
            userData = get(handles.rendezvousGUI,'UserData');
            results = userData{2,1};

            mainUserData = get(userData{1,1}, 'UserData');
            celBodyData = mainUserData{1,1};

            if(~isempty(results))
                set(handlesR.leftTextBox,'String',results.dv1Text);
                set(handlesR.rightTextBox,'String',results.dv2Text);
                plotRendezvous(handlesR.leftAxis, results.timeArr, results.bodyInfo, results.iniOrbit, results.finOrbit, results.xfrOrbit, results.deltaV1, results.deltaV2, celBodyData);
            end
        end

        % Menu selected function: getFinalOrbitFromKSPActiveVesselMenu, 
        % ...and 1 other component
        function getFinalOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getFinalOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.finOrbitSMAText, handles.finOrbitECCText, handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText, handles.finOrbitMEANText, handles.finOrbitEPOCHText);

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                userData = get(handles.rendezvousGUI,'UserData');
                mainUserData = get(userData{1,1},'UserData');
                celBodyData = mainUserData{1,1};

                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                set(handles.orbitingAboutCombo,'Value',value);
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                orbitingAboutCombo_Callback(app,evt);
            end
        end

        % Menu selected function: getFinalOrbitFromKSP, 
        % ...and 1 other component
        function getFinalOrbitFromKSPTOTConnect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getFinalOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.finOrbitSMAText, handles.finOrbitECCText, handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText, handles.finOrbitMEANText, handles.finOrbitEPOCHText);

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                userData = get(handles.rendezvousGUI,'UserData');
                mainUserData = get(userData{1,1},'UserData');
                celBodyData = mainUserData{1,1};

                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                set(handles.orbitingAboutCombo,'Value',value);
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                orbitingAboutCombo_Callback(app,evt);
            end
        end

        % Menu selected function: getFinalOrbitFromSFSFile, 
        % ...and 1 other component
        function getFinalOrbitFromSFSFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getFinalOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.rendezvousGUI,'UserData');
            mainGUIHandle = userData{1,1};
            refBodyID = orbitPanelGetOrbitFromSFSContextCallBack(mainGUIHandle, handles.finOrbitSMAText, handles.finOrbitECCText, handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText, handles.finOrbitMEANText, handles.finOrbitEPOCHText);

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                userData = get(handles.rendezvousGUI,'UserData');
                mainUserData = get(userData{1,1},'UserData');
                celBodyData = mainUserData{1,1};

                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                set(handles.orbitingAboutCombo,'Value',value);
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                orbitingAboutCombo_Callback(app,evt);
            end
        end

        % Menu selected function: getINIOrbitFromKSP, getINIOrbitFromKSP_1
        function getINIOrbitFromKSPTOTConnect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getINIOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectCallBack(handles.iniOrbitSMAText, handles.iniOrbitECCText, handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText, handles.iniOrbitMEANText, handles.iniOrbitEPOCHText);

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                userData = get(handles.rendezvousGUI,'UserData');
                mainUserData = get(userData{1,1},'UserData');
                celBodyData = mainUserData{1,1};

                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                set(handles.orbitingAboutCombo,'Value',value);
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                orbitingAboutCombo_Callback(app,evt);
            end
        end

        % Menu selected function: getINIOrbitFromSFSFile, 
        % ...and 1 other component
        function getINIOrbitFromSFSFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getINIOrbitFromSFSFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.rendezvousGUI,'UserData');
            mainGUIHandle = userData{1,1};
            refBodyID = orbitPanelGetOrbitFromSFSContextCallBack(mainGUIHandle, handles.iniOrbitSMAText, handles.iniOrbitECCText, handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText, handles.iniOrbitMEANText, handles.iniOrbitEPOCHText);

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                userData = get(handles.rendezvousGUI,'UserData');
                mainUserData = get(userData{1,1},'UserData');
                celBodyData = mainUserData{1,1};

                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                set(handles.orbitingAboutCombo,'Value',value);
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                orbitingAboutCombo_Callback(app,evt);
            end
        end

        % Menu selected function: getOrbitFromKSPActiveVesselMenu, 
        % ...and 1 other component
        function getOrbitFromKSPActiveVesselMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to getOrbitFromKSPActiveVesselMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            refBodyID = orbitPanelGetOrbitFromKSPTOTConnectActiveVesselCallBack(handles.iniOrbitSMAText, handles.iniOrbitECCText, handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText, handles.iniOrbitMEANText, handles.iniOrbitEPOCHText);
            set(handles.initialEpochText,'String',get(handles.iniOrbitEPOCHText,'String'));

            if(~isempty(refBodyID) && isnumeric(refBodyID))
                userData = get(handles.rendezvousGUI,'UserData');
                mainUserData = get(userData{1,1},'UserData');
                celBodyData = mainUserData{1,1};

                bodyInfo = getBodyInfoByNumber(refBodyID, celBodyData);
                value = findValueFromComboBox(bodyInfo.name, handles.orbitingAboutCombo);
                set(handles.orbitingAboutCombo,'Value',value);
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                orbitingAboutCombo_Callback(app,evt);
            end
        end

        % Value changed function: iniOrbitARGText
        function iniOrbitARGText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to iniOrbitARGText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of iniOrbitARGText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitARGText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: iniOrbitECCText
        function iniOrbitECCText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to iniOrbitECCText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of iniOrbitECCText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitECCText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: iniOrbitEPOCHText
        function iniOrbitEPOCHText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to iniOrbitEPOCHText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of iniOrbitEPOCHText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitEPOCHText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: iniOrbitINCText
        function iniOrbitINCText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to iniOrbitINCText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of iniOrbitINCText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitINCText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: iniOrbitMEANText
        function iniOrbitMEANText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to iniOrbitMEANText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of iniOrbitMEANText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitMEANText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: iniOrbitRAANText
        function iniOrbitRAANText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to iniOrbitRAANText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of iniOrbitRAANText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitRAANText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: iniOrbitSMAText
        function iniOrbitSMAText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to iniOrbitSMAText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of iniOrbitSMAText as text
            %        str2double(get(hObject,'String')) returns contents of iniOrbitSMAText as a double
            hObject.String = attemptStrEval(hObject.String);
        end

        % Value changed function: iniSelectCelBodyOrbit
        function iniSelectCelBodyOrbit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to iniSelectCelBodyOrbit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns iniSelectCelBodyOrbit contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from iniSelectCelBodyOrbit
            contents = cellstr(get(hObject,'String'));
            selName = contents{get(hObject,'Value')};

            userData = get(handles.rendezvousGUI,'UserData');
            mainUserData = get(userData{1,1},'UserData');
            celBodyData = mainUserData{1,1};

            if(~isempty(selName))
                bodyInfo = celBodyData.(lower(selName));
                set(handles.iniOrbitSMAText, 'String', fullAccNum2Str(bodyInfo.sma));
                set(handles.iniOrbitECCText, 'String', fullAccNum2Str(bodyInfo.ecc));
                set(handles.iniOrbitINCText, 'String', fullAccNum2Str(bodyInfo.inc));
                set(handles.iniOrbitRAANText, 'String', fullAccNum2Str(bodyInfo.raan));
                set(handles.iniOrbitARGText, 'String', fullAccNum2Str(bodyInfo.arg));
                set(handles.iniOrbitMEANText, 'String', fullAccNum2Str(bodyInfo.mean));
                set(handles.iniOrbitEPOCHText, 'String', fullAccNum2Str(bodyInfo.epoch));
                set(hObject, 'Value', 1);
            end
        end

        % Value changed function: initialEpochText
        function initialEpochText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.initialEpochText;
            
            % hObject    handle to initialEpochText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of initialEpochText as text
            %        str2double(get(hObject,'String')) returns contents of initialEpochText as a double
            hObject.Value = attemptStrEval(hObject.Value);

            newInput = hObject.Value;
            inputSec = str2double(newInput);
            if(checkStrIsNumeric(newInput) && inputSec>=0.0)
                [year, day, hour, minute, sec] = convertSec2YearDayHrMnSec(inputSec);
                dateStr = formDateStr(year, day, hour, minute, sec);
                set(handles.startEpochDateLabel, 'String', dateStr);
                hObject.Value = num2str(inputSec);
            end
        end

        % Value changed function: orbitingAboutCombo
        function orbitingAboutCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.orbitingAboutCombo;
            
            % hObject    handle to orbitingAboutCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns orbitingAboutCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from orbitingAboutCombo
            userData = get(handles.rendezvousGUI,'UserData');
            mainUserData = get(userData{1,1},'UserData');
            celBodyData = mainUserData{1,1};

%             contents = cellstr(get(hObject,'String'));
%             cbName = strtrim(lower(contents{get(hObject,'Value')}));
            cbName = strtrim(lower(hObject.Value));

            emptyStr = {''};
            [~, childrenNames] = getChildrenOfParentInfo(celBodyData, cbName);
            childrenNames = cat(2,emptyStr,childrenNames);
            set(handles.iniSelectCelBodyOrbit, 'String', childrenNames);
            set(handles.finalSelectCelBodyOrbit, 'String', childrenNames);
        end

        % Value changed function: paramWgtSlider
        function paramWgtSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.paramWgtSlider;
            
            % hObject    handle to paramWgtSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            v1 = get(hObject,'Value');
            v2 = (v1+1)/2;
            v3 = 0.9*v2+0.1;

            timeWt = v3;
            dvWt = 1/timeWt;

            set(hObject,'UserData',[dvWt timeWt]);
        end

        % Menu selected function: pasteOrbitFromClipboardMenu1, 
        % ...and 1 other component
        function pasteOrbitFromClipboardMenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pasteOrbitFromClipboardMenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.rendezvousGUI,'UserData');
%             results = userData{2,1};
            mainUserData = get(userData{1,1}, 'UserData');
            celBodyData = mainUserData{1,1};

            pasteOrbitFromClipboard(handles.iniOrbitEPOCHText, handles.iniOrbitSMAText, handles.iniOrbitECCText, ...
                handles.iniOrbitINCText, handles.iniOrbitRAANText, handles.iniOrbitARGText, ...
                handles.iniOrbitMEANText, false, handles.orbitingAboutCombo, celBodyData);
        end

        % Menu selected function: pasteOrbitFromClipboardMenu2, 
        % ...and 1 other component
        function pasteOrbitFromClipboardMenu2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pasteOrbitFromClipboardMenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            userData = get(handles.rendezvousGUI,'UserData');
%             results = userData{2,1};
            mainUserData = get(userData{1,1}, 'UserData');
            celBodyData = mainUserData{1,1};

            pasteOrbitFromClipboard(handles.finOrbitEPOCHText, handles.finOrbitSMAText, handles.finOrbitECCText, ...
                handles.finOrbitINCText, handles.finOrbitRAANText, handles.finOrbitARGText, ...
                handles.finOrbitMEANText, false, handles.orbitingAboutCombo, celBodyData);
        end

        % Menu selected function: uploadManeuver1
        function uploadManeuver1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to uploadManeuver1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            guiData = get(handles.dvMan1InfoText,'UserData');
            uploadManeuverToKSP_App(guiData);
        end

        % Menu selected function: uploadManeuver2
        function uploadManeuver2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to uploadManeuver2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            guiData = get(handles.dvMan2InfoText,'UserData');
            uploadManeuverToKSP_App(guiData);
        end

        % Callback function: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable rotation based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            rotate3d(app.rendezvousGUI, char(state));
        end

        % Callback function: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            pan(app.rendezvousGUI, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.rendezvousGUI = uifigure('Visible', 'off');
            colormap(app.rendezvousGUI, 'parula');
            app.rendezvousGUI.Name = 'Rendezvous Maneuver Sequencer';
            app.rendezvousGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.rendezvousGUI.Tag = 'rendezvousGUI';
            app.file = uimenu(app.rendezvousGUI);
            app.file.Text = 'File';
            app.file.Tag = 'file';
            app.generateReport = uimenu(app.file);
            app.generateReport.MenuSelectedFcn = createCallbackFcn(app, @generateReport_Callback, true);
            app.generateReport.Text = 'Generate Report';
            app.generateReport.Tag = 'generateReport';
            app.uitoolbar1 = uitoolbar(app.rendezvousGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Pan';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'pan_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Rotate 3D';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = '3d_rotate.png';
            app.orbitDispAxes = uiaxes(app.rendezvousGUI);
            app.orbitDispAxes.Tag = 'orbitDispAxes';
            app.uipanel2 = uipanel(app.rendezvousGUI);
            app.uipanel2.Title = 'General Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.orbitingAboutCombo = uidropdown(app.uipanel2);
            app.orbitingAboutCombo.Items = {'Pop-up Menu'};
            app.orbitingAboutCombo.ValueChangedFcn = createCallbackFcn(app, @orbitingAboutCombo_Callback, true);
            app.orbitingAboutCombo.Tag = 'orbitingAboutCombo';
            app.orbitingAboutCombo.Tooltip = 'This is the body about which the Initial Orbit is about.';
            app.orbitingAboutCombo.Value = 'Pop-up Menu';
            app.text1 = uilabel(app.uipanel2);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Orbiting About';
            app.uipanel3 = uipanel(app.rendezvousGUI);
            app.uipanel3.Title = 'Initial Orbit Info';
            app.uipanel3.Tag = 'uipanel3';
            app.iniOrbitSMAText = uieditfield(app.uipanel3, 'text');
            app.iniOrbitSMAText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitSMAText_Callback, true);
            app.iniOrbitSMAText.Tag = 'iniOrbitSMAText';
            app.iniOrbitSMAText.Tooltip = 'Semi-major axis of initial orbit.';
            app.iniOrbitSMAText.Value = '700';
            app.iniOrbitECCText = uieditfield(app.uipanel3, 'text');
            app.iniOrbitECCText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitECCText_Callback, true);
            app.iniOrbitECCText.Tag = 'iniOrbitECCText';
            app.iniOrbitECCText.Tooltip = 'Eccentricity of initial orbit.';
            app.iniOrbitECCText.Value = '0.0';
            app.iniOrbitINCText = uieditfield(app.uipanel3, 'text');
            app.iniOrbitINCText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitINCText_Callback, true);
            app.iniOrbitINCText.Tag = 'iniOrbitINCText';
            app.iniOrbitINCText.Tooltip = 'Inclination of initial orbit.';
            app.iniOrbitINCText.Value = '0.0';
            app.iniOrbitRAANText = uieditfield(app.uipanel3, 'text');
            app.iniOrbitRAANText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitRAANText_Callback, true);
            app.iniOrbitRAANText.Tag = 'iniOrbitRAANText';
            app.iniOrbitRAANText.Tooltip = 'Right ascension of the ascending node of initial orbit.';
            app.iniOrbitRAANText.Value = '0.0';
            app.iniOrbitARGText = uieditfield(app.uipanel3, 'text');
            app.iniOrbitARGText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitARGText_Callback, true);
            app.iniOrbitARGText.Tag = 'iniOrbitARGText';
            app.iniOrbitARGText.Tooltip = 'Argument of periapse of initial orbit.';
            app.iniOrbitARGText.Value = '0.0';
            app.text2 = uilabel(app.uipanel3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'km';
            app.text3 = uilabel(app.uipanel3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'deg';
            app.text4 = uilabel(app.uipanel3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'deg';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'deg';
            app.iniSMA = uilabel(app.uipanel3);
            app.iniSMA.Tag = 'iniSMA';
            app.iniSMA.Text = 'SMA';
            app.iniEcc = uilabel(app.uipanel3);
            app.iniEcc.Tag = 'iniEcc';
            app.iniEcc.Text = 'Ecc.';
            app.iniInc = uilabel(app.uipanel3);
            app.iniInc.Tag = 'iniInc';
            app.iniInc.Text = 'Inc.';
            app.iniRAAN = uilabel(app.uipanel3);
            app.iniRAAN.Tag = 'iniRAAN';
            app.iniRAAN.Text = 'RAAN';
            app.iniArgPeri = uilabel(app.uipanel3);
            app.iniArgPeri.Tag = 'iniArgPeri';
            app.iniArgPeri.Text = 'Arg. Peri.';
            app.text52 = uilabel(app.uipanel3);
            app.text52.Tag = 'text52';
            app.text52.Text = 'Mean Anom.';
            app.text53 = uilabel(app.uipanel3);
            app.text53.Tag = 'text53';
            app.text53.Text = 'Epoch';
            app.iniOrbitMEANText = uieditfield(app.uipanel3, 'text');
            app.iniOrbitMEANText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitMEANText_Callback, true);
            app.iniOrbitMEANText.Tag = 'iniOrbitMEANText';
            app.iniOrbitMEANText.Tooltip = 'Orbit''s mean anomaly at epoch.';
            app.iniOrbitMEANText.Value = '0.0';
            app.iniOrbitEPOCHText = uieditfield(app.uipanel3, 'text');
            app.iniOrbitEPOCHText.ValueChangedFcn = createCallbackFcn(app, @iniOrbitEPOCHText_Callback, true);
            app.iniOrbitEPOCHText.Tag = 'iniOrbitEPOCHText';
            app.iniOrbitEPOCHText.Tooltip = 'Epoch of the orbit.';
            app.iniOrbitEPOCHText.Value = '0.0';
            app.text54 = uilabel(app.uipanel3);
            app.text54.Tag = 'text54';
            app.text54.Text = 'deg';
            app.text55 = uilabel(app.uipanel3);
            app.text55.Tag = 'text55';
            app.text55.Text = 'sec';
            app.iniSelectCelBodyOrbit = uidropdown(app.uipanel3);
            app.iniSelectCelBodyOrbit.Items = {};
            app.iniSelectCelBodyOrbit.ValueChangedFcn = createCallbackFcn(app, @iniSelectCelBodyOrbit_Callback, true);
            app.iniSelectCelBodyOrbit.Tag = 'iniSelectCelBodyOrbit';
            app.iniSelectCelBodyOrbit.Tooltip = 'Select a Celestial Body to Use Its Orbit';
            app.iniSelectCelBodyOrbit.Value = {};
            app.uipanel4 = uipanel(app.rendezvousGUI);
            app.uipanel4.Title = 'Final (Desired) Orbit Info';
            app.uipanel4.Tag = 'uipanel4';
            app.finOrbitSMAText = uieditfield(app.uipanel4, 'text');
            app.finOrbitSMAText.ValueChangedFcn = createCallbackFcn(app, @finOrbitSMAText_Callback, true);
            app.finOrbitSMAText.Tag = 'finOrbitSMAText';
            app.finOrbitSMAText.Tooltip = 'Semi-major axis of final orbit.';
            app.finOrbitSMAText.Value = '800';
            app.finOrbitECCText = uieditfield(app.uipanel4, 'text');
            app.finOrbitECCText.ValueChangedFcn = createCallbackFcn(app, @finOrbitECCText_Callback, true);
            app.finOrbitECCText.Tag = 'finOrbitECCText';
            app.finOrbitECCText.Tooltip = 'Eccentricity of final orbit.';
            app.finOrbitECCText.Value = '0.0';
            app.finOrbitINCText = uieditfield(app.uipanel4, 'text');
            app.finOrbitINCText.ValueChangedFcn = createCallbackFcn(app, @finOrbitINCText_Callback, true);
            app.finOrbitINCText.Tag = 'finOrbitINCText';
            app.finOrbitINCText.Tooltip = 'Inclination of final orbit.';
            app.finOrbitINCText.Value = '0.0';
            app.finOrbitRAANText = uieditfield(app.uipanel4, 'text');
            app.finOrbitRAANText.ValueChangedFcn = createCallbackFcn(app, @finOrbitRAANText_Callback, true);
            app.finOrbitRAANText.Tag = 'finOrbitRAANText';
            app.finOrbitRAANText.Tooltip = 'Right ascension of the ascending node of final orbit.';
            app.finOrbitRAANText.Value = '0.0';
            app.finOrbitARGText = uieditfield(app.uipanel4, 'text');
            app.finOrbitARGText.ValueChangedFcn = createCallbackFcn(app, @finOrbitARGText_Callback, true);
            app.finOrbitARGText.Tag = 'finOrbitARGText';
            app.finOrbitARGText.Tooltip = 'Argument of periapse of final orbit.';
            app.finOrbitARGText.Value = '0.0';
            app.text11 = uilabel(app.uipanel4);
            app.text11.Tag = 'text11';
            app.text11.Text = 'km';
            app.text12 = uilabel(app.uipanel4);
            app.text12.Tag = 'text12';
            app.text12.Text = 'deg';
            app.text13 = uilabel(app.uipanel4);
            app.text13.Tag = 'text13';
            app.text13.Text = 'deg';
            app.text14 = uilabel(app.uipanel4);
            app.text14.Tag = 'text14';
            app.text14.Text = 'deg';
            app.text15 = uilabel(app.uipanel4);
            app.text15.Tag = 'text15';
            app.text15.Text = 'SMA';
            app.text16 = uilabel(app.uipanel4);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Ecc.';
            app.text17 = uilabel(app.uipanel4);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Inc.';
            app.text18 = uilabel(app.uipanel4);
            app.text18.Tag = 'text18';
            app.text18.Text = 'RAAN';
            app.text19 = uilabel(app.uipanel4);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Arg. Peri.';
            app.text56 = uilabel(app.uipanel4);
            app.text56.Tag = 'text56';
            app.text56.Text = 'Mean Anom.';
            app.text57 = uilabel(app.uipanel4);
            app.text57.Tag = 'text57';
            app.text57.Text = 'Epoch';
            app.finOrbitMEANText = uieditfield(app.uipanel4, 'text');
            app.finOrbitMEANText.ValueChangedFcn = createCallbackFcn(app, @finOrbitMEANText_Callback, true);
            app.finOrbitMEANText.Tag = 'finOrbitMEANText';
            app.finOrbitMEANText.Tooltip = 'Orbit''s mean anomaly at epoch.';
            app.finOrbitMEANText.Value = '0.0';
            app.finOrbitEPOCHText = uieditfield(app.uipanel4, 'text');
            app.finOrbitEPOCHText.ValueChangedFcn = createCallbackFcn(app, @finOrbitEPOCHText_Callback, true);
            app.finOrbitEPOCHText.Tag = 'finOrbitEPOCHText';
            app.finOrbitEPOCHText.Tooltip = 'Epoch of the orbit.';
            app.finOrbitEPOCHText.Value = '0.0';
            app.text58 = uilabel(app.uipanel4);
            app.text58.Tag = 'text58';
            app.text58.Text = 'deg';
            app.text59 = uilabel(app.uipanel4);
            app.text59.Tag = 'text59';
            app.text59.Text = 'sec';
            app.finalSelectCelBodyOrbit = uidropdown(app.uipanel4);
            app.finalSelectCelBodyOrbit.Items = {};
            app.finalSelectCelBodyOrbit.ValueChangedFcn = createCallbackFcn(app, @finalSelectCelBodyOrbit_Callback, true);
            app.finalSelectCelBodyOrbit.Tag = 'finalSelectCelBodyOrbit';
            app.finalSelectCelBodyOrbit.Tooltip = 'Select a Celestial Body to Use Its Orbit';
            app.finalSelectCelBodyOrbit.Value = {};
            app.xfrOrbitText = uitextarea(app.rendezvousGUI);
            app.xfrOrbitText.Tag = 'xfrOrbitText';
            app.xfrOrbitText.Value = {'No Rendezvous Maneuver Computed Yet'};
            app.dvMan2InfoText = uitextarea(app.rendezvousGUI);
            app.dvMan2InfoText.Tag = 'dvMan2InfoText';
            app.dvMan2InfoText.Value = {'No Rendezvous Maneuver Computed Yet'};
            app.dvMan1InfoText = uitextarea(app.rendezvousGUI);
            app.dvMan1InfoText.Tag = 'dvMan1InfoText';
            app.dvMan1InfoText.Value = {'No Rendezvous Maneuver Computed Yet'};
            app.text23 = uilabel(app.rendezvousGUI);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Transfer Orbit Information';
            app.text24 = uilabel(app.rendezvousGUI);
            app.text24.Tag = 'text24';
            app.text24.Text = 'DV Maneuver 1 Information';
            app.text25 = uilabel(app.rendezvousGUI);
            app.text25.Tag = 'text25';
            app.text25.Text = 'DV Maneuver 2 Information';
            app.computeRendezvousButton = uibutton(app.rendezvousGUI, 'push');
            app.computeRendezvousButton.ButtonPushedFcn = createCallbackFcn(app, @computeRendezvousButton_Callback, true);
            app.computeRendezvousButton.Tag = 'computeRendezvousButton';
            app.computeRendezvousButton.Tooltip = 'Computes a rendevous ';
            app.computeRendezvousButton.Text = 'Compute Rendezvous Maneuvers';
            app.uipanel10 = uipanel(app.rendezvousGUI);
            app.uipanel10.Title = 'Search Initial Epoch';
            app.uipanel10.Tag = 'uipanel10';
            app.text63 = uilabel(app.uipanel10);
            app.text63.Tag = 'text63';
            app.text63.Text = 'Initial Epoch';
            app.initialEpochText = uieditfield(app.uipanel10, 'text');
            app.initialEpochText.ValueChangedFcn = createCallbackFcn(app, @initialEpochText_Callback, true);
            app.initialEpochText.Tag = 'initialEpochText';
            app.initialEpochText.Tooltip = 'Seconds from game start (right-click to enter as date)';
            app.initialEpochText.Value = '0.0';
            app.text64 = uilabel(app.uipanel10);
            app.text64.Tag = 'text64';
            app.text64.Text = 'sec';
            app.startEpochDateLabel = uilabel(app.uipanel10);
            app.startEpochDateLabel.Tag = 'startEpochDateLabel';
            app.startEpochDateLabel.Text = 'Date';
            app.uipanel12 = uipanel(app.rendezvousGUI);
            app.uipanel12.Title = 'Search Parameters';
            app.uipanel12.Tag = 'uipanel12';
            app.text71 = uilabel(app.uipanel12);
            app.text71.Tag = 'text71';
            app.text71.Text = 'Search Window Length';
            app.searchDurationText = uieditfield(app.uipanel12, 'text');
            app.searchDurationText.Tag = 'searchDurationText';
            app.searchDurationText.Tooltip = 'This number added to the initial epoch value is the maximum starting epoch of the first burn in the rendezvous maneuver.';
            app.searchDurationText.Value = '10000';
            app.text72 = uilabel(app.uipanel12);
            app.text72.Tag = 'text72';
            app.text72.Text = 'sec';
            app.text76 = uilabel(app.uipanel12);
            app.text76.Tag = 'text76';
            app.text76.Text = 'Search Parameter Weightings';
            app.paramWgtSlider = uislider(app.uipanel12);
            app.paramWgtSlider.Limits = [-1 1];
            app.paramWgtSlider.MajorTicks = [];
            app.paramWgtSlider.ValueChangedFcn = createCallbackFcn(app, @paramWgtSlider_Callback, true);
            app.paramWgtSlider.MinorTicks = [];
            app.paramWgtSlider.Tag = 'paramWgtSlider';
            app.paramWgtSlider.Value = -1;
            app.text77 = uilabel(app.uipanel12);
            app.text77.Tag = 'text77';
            app.text77.Tooltip = 'Delta-V';
            app.text77.Text = 'DV';
            app.text78 = uilabel(app.uipanel12);
            app.text78.Tag = 'text78';
            app.text78.Tooltip = 'Transfer orbit duration';
            app.text78.Text = 'TOD';
            app.uipanel13 = uipanel(app.rendezvousGUI);
            app.uipanel13.Title = 'Additional Search Parameters';
            app.uipanel13.Tag = 'uipanel13';
            app.optBurn1OnlyCheckbox = uicheckbox(app.uipanel13);
            app.optBurn1OnlyCheckbox.Tag = 'optBurn1OnlyCheckbox';
            app.optBurn1OnlyCheckbox.Tooltip = 'When checked, only the delta-v of the first maneuver will be considered.  Useful for when you want to go somewhere but are not concerned with stopping.';
            app.optBurn1OnlyCheckbox.Text = 'Optimize Intercept Burn Only?';
            app.orbitContMenu = uicontextmenu(app.rendezvousGUI);
            app.orbitContMenu.Tag = 'orbitContMenu';
            app.getINIOrbitFromSFSFile = uimenu(app.orbitContMenu);
            app.getINIOrbitFromSFSFile.MenuSelectedFcn = createCallbackFcn(app, @getINIOrbitFromSFSFile_Callback, true);
            app.getINIOrbitFromSFSFile.Text = 'Get Orbit From SFS File';
            app.getINIOrbitFromSFSFile.Tag = 'getINIOrbitFromSFSFile';
            app.getINIOrbitFromKSP = uimenu(app.orbitContMenu);
            app.getINIOrbitFromKSP.MenuSelectedFcn = createCallbackFcn(app, @getINIOrbitFromKSPTOTConnect_Callback, true);
            app.getINIOrbitFromKSP.Text = 'Get Orbit From KSP';
            app.getINIOrbitFromKSP.Tag = 'getINIOrbitFromKSP';
            app.getOrbitFromKSPActiveVesselMenu = uimenu(app.orbitContMenu);
            app.getOrbitFromKSPActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu.Tag = 'getOrbitFromKSPActiveVesselMenu';
            app.copyOrbitToClipboardMenu1 = uimenu(app.orbitContMenu);
            app.copyOrbitToClipboardMenu1.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu1_Callback, true);
            app.copyOrbitToClipboardMenu1.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu1.Tag = 'copyOrbitToClipboardMenu1';
            app.pasteOrbitFromClipboardMenu1 = uimenu(app.orbitContMenu);
            app.pasteOrbitFromClipboardMenu1.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu1_Callback, true);
            app.pasteOrbitFromClipboardMenu1.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu1.Tag = 'pasteOrbitFromClipboardMenu1';
            % Assign app.orbitContMenu
            app.uipanel3.ContextMenu = app.orbitContMenu;
            app.iniOrbitSMAText.ContextMenu = app.orbitContMenu;
            app.iniOrbitECCText.ContextMenu = app.orbitContMenu;
            app.iniOrbitINCText.ContextMenu = app.orbitContMenu;
            app.iniOrbitRAANText.ContextMenu = app.orbitContMenu;
            app.iniOrbitARGText.ContextMenu = app.orbitContMenu;
            app.text2.ContextMenu = app.orbitContMenu;
            app.text3.ContextMenu = app.orbitContMenu;
            app.text4.ContextMenu = app.orbitContMenu;
            app.text5.ContextMenu = app.orbitContMenu;
            app.iniSMA.ContextMenu = app.orbitContMenu;
            app.iniEcc.ContextMenu = app.orbitContMenu;
            app.iniInc.ContextMenu = app.orbitContMenu;
            app.iniRAAN.ContextMenu = app.orbitContMenu;
            app.iniArgPeri.ContextMenu = app.orbitContMenu;
            app.text52.ContextMenu = app.orbitContMenu;
            app.text53.ContextMenu = app.orbitContMenu;
            app.text54.ContextMenu = app.orbitContMenu;
            app.text55.ContextMenu = app.orbitContMenu;
            app.iniSelectCelBodyOrbit.ContextMenu = app.orbitContMenu;
            app.orbitContMenuWithGetDateTime = uicontextmenu(app.rendezvousGUI);
            app.orbitContMenuWithGetDateTime.Tag = 'orbitContMenuWithGetDateTime';
            app.enterUTAsDateTime = uimenu(app.orbitContMenuWithGetDateTime);
            app.enterUTAsDateTime.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime.Tag = 'enterUTAsDateTime';
            app.getINIOrbitFromSFSFile_1 = uimenu(app.orbitContMenuWithGetDateTime);
            app.getINIOrbitFromSFSFile_1.MenuSelectedFcn = createCallbackFcn(app, @getINIOrbitFromSFSFile_Callback, true);
            app.getINIOrbitFromSFSFile_1.Text = 'Get Orbit From SFS File';
            app.getINIOrbitFromSFSFile_1.Tag = 'getINIOrbitFromSFSFile_1';
            app.getINIOrbitFromKSP_1 = uimenu(app.orbitContMenuWithGetDateTime);
            app.getINIOrbitFromKSP_1.MenuSelectedFcn = createCallbackFcn(app, @getINIOrbitFromKSPTOTConnect_Callback, true);
            app.getINIOrbitFromKSP_1.Text = 'Get Orbit From KSP';
            app.getINIOrbitFromKSP_1.Tag = 'getINIOrbitFromKSP_1';
            app.getOrbitFromKSPActiveVesselMenu_1 = uimenu(app.orbitContMenuWithGetDateTime);
            app.getOrbitFromKSPActiveVesselMenu_1.MenuSelectedFcn = createCallbackFcn(app, @getOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getOrbitFromKSPActiveVesselMenu_1.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getOrbitFromKSPActiveVesselMenu_1.Tag = 'getOrbitFromKSPActiveVesselMenu_1';
            app.copyOrbitToClipboardMenu1_1 = uimenu(app.orbitContMenuWithGetDateTime);
            app.copyOrbitToClipboardMenu1_1.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu1_Callback, true);
            app.copyOrbitToClipboardMenu1_1.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu1_1.Tag = 'copyOrbitToClipboardMenu1_1';
            app.pasteOrbitFromClipboardMenu1_1 = uimenu(app.orbitContMenuWithGetDateTime);
            app.pasteOrbitFromClipboardMenu1_1.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu1_Callback, true);
            app.pasteOrbitFromClipboardMenu1_1.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu1_1.Tag = 'pasteOrbitFromClipboardMenu1_1';
            % Assign app.orbitContMenuWithGetDateTime
            app.iniOrbitEPOCHText.ContextMenu = app.orbitContMenuWithGetDateTime;
            app.finOrbitEPOCHText.ContextMenu = app.orbitContMenuWithGetDateTime;
            app.finOrbitContMenu = uicontextmenu(app.rendezvousGUI);
            app.finOrbitContMenu.Tag = 'finOrbitContMenu';
            app.getFinalOrbitFromSFSFile = uimenu(app.finOrbitContMenu);
            app.getFinalOrbitFromSFSFile.MenuSelectedFcn = createCallbackFcn(app, @getFinalOrbitFromSFSFile_Callback, true);
            app.getFinalOrbitFromSFSFile.Text = 'Get Orbit From SFS File';
            app.getFinalOrbitFromSFSFile.Tag = 'getFinalOrbitFromSFSFile';
            app.getFinalOrbitFromKSP = uimenu(app.finOrbitContMenu);
            app.getFinalOrbitFromKSP.MenuSelectedFcn = createCallbackFcn(app, @getFinalOrbitFromKSPTOTConnect_Callback, true);
            app.getFinalOrbitFromKSP.Text = 'Get Orbit From KSP';
            app.getFinalOrbitFromKSP.Tag = 'getFinalOrbitFromKSP';
            app.getFinalOrbitFromKSPActiveVesselMenu = uimenu(app.finOrbitContMenu);
            app.getFinalOrbitFromKSPActiveVesselMenu.MenuSelectedFcn = createCallbackFcn(app, @getFinalOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getFinalOrbitFromKSPActiveVesselMenu.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getFinalOrbitFromKSPActiveVesselMenu.Tag = 'getFinalOrbitFromKSPActiveVesselMenu';
            app.copyOrbitToClipboardMenu2 = uimenu(app.finOrbitContMenu);
            app.copyOrbitToClipboardMenu2.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitToClipboardMenu2_Callback, true);
            app.copyOrbitToClipboardMenu2.Text = 'Copy Orbit to Clipboard';
            app.copyOrbitToClipboardMenu2.Tag = 'copyOrbitToClipboardMenu2';
            app.pasteOrbitFromClipboardMenu2 = uimenu(app.finOrbitContMenu);
            app.pasteOrbitFromClipboardMenu2.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu2_Callback, true);
            app.pasteOrbitFromClipboardMenu2.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu2.Tag = 'pasteOrbitFromClipboardMenu2';
            % Assign app.finOrbitContMenu
            app.uipanel4.ContextMenu = app.finOrbitContMenu;
            app.finOrbitSMAText.ContextMenu = app.finOrbitContMenu;
            app.finOrbitECCText.ContextMenu = app.finOrbitContMenu;
            app.finOrbitINCText.ContextMenu = app.finOrbitContMenu;
            app.finOrbitRAANText.ContextMenu = app.finOrbitContMenu;
            app.finOrbitARGText.ContextMenu = app.finOrbitContMenu;
            app.text11.ContextMenu = app.finOrbitContMenu;
            app.text12.ContextMenu = app.finOrbitContMenu;
            app.text13.ContextMenu = app.finOrbitContMenu;
            app.text14.ContextMenu = app.finOrbitContMenu;
            app.text15.ContextMenu = app.finOrbitContMenu;
            app.text16.ContextMenu = app.finOrbitContMenu;
            app.text17.ContextMenu = app.finOrbitContMenu;
            app.text18.ContextMenu = app.finOrbitContMenu;
            app.text19.ContextMenu = app.finOrbitContMenu;
            app.text56.ContextMenu = app.finOrbitContMenu;
            app.text57.ContextMenu = app.finOrbitContMenu;
            app.finOrbitMEANText.ContextMenu = app.finOrbitContMenu;
            app.text58.ContextMenu = app.finOrbitContMenu;
            app.text59.ContextMenu = app.finOrbitContMenu;
            app.finalSelectCelBodyOrbit.ContextMenu = app.finOrbitContMenu;
            app.finOrbitContMenuWithGetDateTime = uicontextmenu(app.rendezvousGUI);
            app.finOrbitContMenuWithGetDateTime.Tag = 'finOrbitContMenuWithGetDateTime';
            app.enterFinUTAsDateTime = uimenu(app.finOrbitContMenuWithGetDateTime);
            app.enterFinUTAsDateTime.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterFinUTAsDateTime.Text = 'Enter UT As Date/Time';
            app.enterFinUTAsDateTime.Tag = 'enterFinUTAsDateTime';
            app.getFinalOrbitFromSFSFile_1 = uimenu(app.finOrbitContMenuWithGetDateTime);
            app.getFinalOrbitFromSFSFile_1.MenuSelectedFcn = createCallbackFcn(app, @getFinalOrbitFromSFSFile_Callback, true);
            app.getFinalOrbitFromSFSFile_1.Text = 'Get Orbit From SFS File';
            app.getFinalOrbitFromSFSFile_1.Tag = 'getFinalOrbitFromSFSFile_1';
            app.getFinalOrbitFromKSP_1 = uimenu(app.finOrbitContMenuWithGetDateTime);
            app.getFinalOrbitFromKSP_1.MenuSelectedFcn = createCallbackFcn(app, @getFinalOrbitFromKSPTOTConnect_Callback, true);
            app.getFinalOrbitFromKSP_1.Text = 'Get Orbit From KSP';
            app.getFinalOrbitFromKSP_1.Tag = 'getFinalOrbitFromKSP_1';
            app.getFinalOrbitFromKSPActiveVesselMenu_1 = uimenu(app.finOrbitContMenuWithGetDateTime);
            app.getFinalOrbitFromKSPActiveVesselMenu_1.MenuSelectedFcn = createCallbackFcn(app, @getFinalOrbitFromKSPActiveVesselMenu_Callback, true);
            app.getFinalOrbitFromKSPActiveVesselMenu_1.Text = 'Get Orbit From KSP (Active Vessel)';
            app.getFinalOrbitFromKSPActiveVesselMenu_1.Tag = 'getFinalOrbitFromKSPActiveVesselMenu_1';
            app.Untitled_7 = uimenu(app.finOrbitContMenuWithGetDateTime);
            app.Untitled_7.Text = 'Copy Orbit to Clipboard';
            app.Untitled_7.Tag = 'Untitled_7';
            app.pasteOrbitFromClipboardMenu2_1 = uimenu(app.finOrbitContMenuWithGetDateTime);
            app.pasteOrbitFromClipboardMenu2_1.MenuSelectedFcn = createCallbackFcn(app, @pasteOrbitFromClipboardMenu2_Callback, true);
            app.pasteOrbitFromClipboardMenu2_1.Text = 'Paste Orbit From Clipboard';
            app.pasteOrbitFromClipboardMenu2_1.Tag = 'pasteOrbitFromClipboardMenu2_1';
            app.enterStartEpochUTAsDateTime = uicontextmenu(app.rendezvousGUI);
            app.enterStartEpochUTAsDateTime.Tag = 'enterStartEpochUTAsDateTime';
            app.enterRendStartEpochUTAsDateTime = uimenu(app.enterStartEpochUTAsDateTime);
            app.enterRendStartEpochUTAsDateTime.MenuSelectedFcn = createCallbackFcn(app, @enterRendStartEpochUTAsDateTime_Callback, true);
            app.enterRendStartEpochUTAsDateTime.Text = 'Enter UT As Date/Time';
            app.enterRendStartEpochUTAsDateTime.Tag = 'enterRendStartEpochUTAsDateTime';
            % Assign app.enterStartEpochUTAsDateTime
            app.initialEpochText.ContextMenu = app.enterStartEpochUTAsDateTime;
            app.uploadManuever1ToKSP = uicontextmenu(app.rendezvousGUI);
            app.uploadManuever1ToKSP.Tag = 'uploadManuever1ToKSP';
            app.uploadManeuver1 = uimenu(app.uploadManuever1ToKSP);
            app.uploadManeuver1.MenuSelectedFcn = createCallbackFcn(app, @uploadManeuver1_Callback, true);
            app.uploadManeuver1.Text = 'Upload Maneuver to KSP';
            app.uploadManeuver1.Tag = 'uploadManeuver1';
            % Assign app.uploadManuever1ToKSP
            app.dvMan1InfoText.ContextMenu = app.uploadManuever1ToKSP;
            app.uploadManuever2ToKSP = uicontextmenu(app.rendezvousGUI);
            app.uploadManuever2ToKSP.Tag = 'uploadManuever2ToKSP';
            app.uploadManeuver2 = uimenu(app.uploadManuever2ToKSP);
            app.uploadManeuver2.MenuSelectedFcn = createCallbackFcn(app, @uploadManeuver2_Callback, true);
            app.uploadManeuver2.Text = 'Upload Maneuver to KSP';
            app.uploadManeuver2.Tag = 'uploadManeuver2';
            % Assign app.uploadManuever2ToKSP
            app.dvMan2InfoText.ContextMenu = app.uploadManuever2ToKSP;
            % Show the figure after all components are created
            app.rendezvousGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rendezvousGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.rendezvousGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)rendezvousGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.rendezvousGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.rendezvousGUI)
        end
    end
end